classdef PLOTTER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        LoggerTab                    matlab.ui.container.Tab
        SelectedloggerpositionchangerLabel  matlab.ui.control.Label
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
        loadingLabel                 matlab.ui.control.Label
        ExampleofthelistLabel        matlab.ui.control.Label
        LOGGERPanel                  matlab.ui.container.Panel
        Label4alpha                  matlab.ui.control.Label
        AlphaSpinner                 matlab.ui.control.Spinner
        AlphaLabel                   matlab.ui.control.Label
        LinecolorColorPicker         matlab.ui.control.ColorPicker
        LinecolorColorPickerLabel    matlab.ui.control.Label
        LegendnameEditField          matlab.ui.control.EditField
        LegendnameEditFieldLabel     matlab.ui.control.Label
        LoadaddmatfileLOGGERButton   matlab.ui.control.Button
        DeletecheckedLOGGERButton    matlab.ui.control.Button
        DeleteallLOGGERButton        matlab.ui.control.Button
        AlphavalueLabel              matlab.ui.control.Label
        LegendNameLabel              matlab.ui.control.Label
        FullpathLabel                matlab.ui.control.Label
        LoadedmatfileLabel           matlab.ui.control.Label
        SetLoggerButton              matlab.ui.control.Button
        LOGGERlistLabel              matlab.ui.control.Label
        LoggerTree                   matlab.ui.container.CheckBoxTree
        PlotSaveTab                  matlab.ui.container.Tab
        ClosefigureswithoutcurrentsettingsButton  matlab.ui.control.Button
        CloseallfiguresButton        matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        SavesettingsPanel            matlab.ui.container.Panel
        ExceloutputLabel             matlab.ui.control.Label
        ControlcostoutputCheckBox    matlab.ui.control.CheckBox
        RMSEvaluesoutputCheckBox     matlab.ui.control.CheckBox
        CurrentsavefolderEditField   matlab.ui.control.EditField
        CurrentsavefolderEditFieldLabel  matlab.ui.control.Label
        SavestyleButtonGroup         matlab.ui.container.ButtonGroup
        fig                          matlab.ui.control.RadioButton
        epsButton                    matlab.ui.control.RadioButton
        pngButton                    matlab.ui.control.RadioButton
        jpgButton                    matlab.ui.control.RadioButton
        pdfButton                    matlab.ui.control.RadioButton
        SavenameEditField            matlab.ui.control.EditField
        SavenameEditFieldLabel       matlab.ui.control.Label
        SelectsavefolderButton       matlab.ui.control.Button
        SavefigButton                matlab.ui.control.Button
        TargetsAttributesLabelssettingsPanel  matlab.ui.container.Panel
        loadingLabel4settings        matlab.ui.control.Label
        TargetLabel                  matlab.ui.control.Label
        LoadsettingsButton           matlab.ui.control.Button
        onefigLamp                   matlab.ui.control.Lamp
        subfigLamp                   matlab.ui.control.Lamp
        PlotmodeSwitch               matlab.ui.control.Switch
        PlotmodeSwitchLabel          matlab.ui.control.Label
        AddtargetButton              matlab.ui.control.Button
        EditselectedtargetButton     matlab.ui.control.Button
        DeleteselectedtargetButton   matlab.ui.control.Button
        TargetDropDown               matlab.ui.control.DropDown
        UITable                      matlab.ui.control.Table
        PlotsettingsPanel            matlab.ui.container.Panel
        ShowlegendCheckBox           matlab.ui.control.CheckBox
        Label4FS                     matlab.ui.control.Label
        Label4LW                     matlab.ui.control.Label
        MinorgridCheckBox            matlab.ui.control.CheckBox
        PhasecolorflagCheckBox       matlab.ui.control.CheckBox
        ArmingCheckBox               matlab.ui.control.CheckBox
        TakeoffCheckBox              matlab.ui.control.CheckBox
        FlightCheckBox               matlab.ui.control.CheckBox
        LandingCheckBox              matlab.ui.control.CheckBox
        LandingLabel                 matlab.ui.control.Label
        FlightLabel                  matlab.ui.control.Label
        TakeoffLabel                 matlab.ui.control.Label
        ArmingLabel                  matlab.ui.control.Label
        MaxtimeSpinner               matlab.ui.control.Spinner
        MaxtimeSpinnerLabel          matlab.ui.control.Label
        MintimeSpinner               matlab.ui.control.Spinner
        MintimeSpinnerLabel          matlab.ui.control.Label
        PhaseLabel                   matlab.ui.control.Label
        LineWidthSpinner             matlab.ui.control.Spinner
        LineWidthSpinnerLabel        matlab.ui.control.Label
        LegendFontSizeSpinner        matlab.ui.control.Spinner
        LabelFontSizeSpinner_2Label  matlab.ui.control.Label
        LabelFontSizeSpinner         matlab.ui.control.Spinner
        LabelFontSizeSpinnerLabel    matlab.ui.control.Label
        AxisFontSizeSpinner          matlab.ui.control.Spinner
        AxisFontSizeSpinnerLabel     matlab.ui.control.Label
        TimerangeSlider              matlab.ui.control.RangeSlider
        TimerangeLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        filepathes  cell
        filenames   cell
        logger  % .LOGGER  .alpha  .lgdName <- (:,1)cell
        Num     double
        mode    string = "subfig"; % "subfig" or "onefig"を切り替える変数
        settings    % プロット関連の設定を入れる構造体
        tableData   % GUI上のtableDataを管理
        % .subfig    .onefig 
        map         % プロット時の関係性をマッピングする構造体．全てcell配列で扱う．Table型で扱いたい(2026/1/15での願望)
        data
        % .time (:,1)double    .idx (1,2)double =app.settings.phaseの中でのindex
        save        % 保存関連の設定を入れる構造体
    end
    
    properties (Access = private)
        IsAuthorized = false; % PlotSaveTabへの遷移を管理
        IsLoggerSetting = false; % LOGGER関連の設定がユーザーによって行われたらtrue
        IsPlotSetting   = false; % PlotSetting関連の設定がユーザーによって行われたらtrue
        IsSaveSetting   = false; % SaveSettings関連の設定が完了したらtrue. 現状Savenameが登録されたらtrue(2026/01/13)
        GrayColor = [0.8, 0.8, 0.8];
        RedColor = [1, 0.3, 0.3];
        tmpRange % Timerangeスライダー用に一時的に保存する用

        % ウィンドウ配置管理用
        currentStartX = 50;  
        colCount = 0;
        scrW = 0; % スクリーン幅
        scrH = 0; % スクリーン高さ
    end
    
    methods (Access = public)
        
        function [LowerTime, UpperTime] = extract_time(app, opt)
            %extract_timeメソッド
            % 現在のapp.logger.LOGGER, app.settings.phaseから時間を取り出す
            % GUI上のスライダーやスピナーも動的に変更する
            arguments
                app 
                opt.max double
                opt.min double
            end
            if ~isfield(opt,"max") && ~isfield(opt,"min") % 引数に指定がない場合 = 現状[app.SetLoggerButton]を押下した時 and phaseを変更した時(2026/01/13)
                app.data.time   = cell(app.Num, 1);
                app.data.idx    = cell(app.Num, 1);
            end
            LowerTime = 0;  % 初期化用
            UpperTime = 100;    % 初期化用
            for N = 1:app.Num
                tmp = app.logger.LOGGER{N}.data(0,"t","", "phase",app.settings.phase);
                tmpTime  = tmp - tmp(1);
                if isfield(opt,"max") && isfield(opt,"min") % 両引数に指定がある場合
                    start_idx   = find(tmpTime >= opt.min, 1, "first");
                    last_idx    = find(tmpTime <= opt.max, 1, "last");
                else % 引数に指定が無い場合 = 現状[app.SetLoggerButton]を押下した時 and phaseを変更した時(2026/01/13)
                    start_idx   = 1;
                    last_idx    = numel(tmpTime);
                end
                app.data.time{N} = tmpTime(start_idx:last_idx);
                app.data.idx{N}  = [start_idx, last_idx];
                if app.data.time{N}(1) > LowerTime, LowerTime = app.data.time{N}(1); end
                if app.data.time{N}(end) < UpperTime, UpperTime = app.data.time{N}(end); end
            end

            % ----------- GUI関連の設定 ----------- %
            if ~isfield(opt,"max") && ~isfield(opt,"min") % 引数に指定がない場合
                app.TimerangeSlider.Limits = [LowerTime, UpperTime];
                app.TimerangeSlider.Value = [LowerTime, UpperTime];
                app.MintimeSpinner.Limits = [LowerTime, UpperTime];
                app.MintimeSpinner.Value = LowerTime;
                app.MaxtimeSpinner.Limits = [LowerTime, UpperTime];
                app.MaxtimeSpinner.Value = UpperTime;
            end
        end


        function onefig_plot(app, DesignatedCol)
            arguments
                app 
                DesignatedCol double = 0;
            end
            if DesignatedCol ~= 0 % 引数に指定がある場合
                target = app.map.target{DesignatedCol};     % 'char'
                clearn_target = app.map.clearn_target{DesignatedCol}; % 'char'
                splited_att = app.map.splited_att{DesignatedCol}; % "string" array
                xlabels = app.map.xlabel{DesignatedCol};    % 'char' in {cell}
                ylabels = app.map.ylabel{DesignatedCol};    % 'char' in {cell}
                zFlag = false;
                zlabels = app.map.zlabel{DesignatedCol};    % 'char' in {cell}
                if ~isempty(zlabels{1}), zFlag = true; end  % zlabelsが空でなければフラグを立てる
                figNum = app.map.fignum{DesignatedCol};     % double

                f = figure(figNum);
                f.Name = clearn_target;
                clf;
                app.adjust_figure_position(f);
                tiledlayout(1,1);
                ax = nexttile(1);
                plegend = {};
                hold on;
                xMax = -Inf;
                xMin = Inf;
                yMax = -Inf;
                yMin = Inf;
                if zFlag
                    zMax = -Inf;
                    zMin = Inf;
                end
                for N = 1:app.Num
                    for att = splited_att
                        idx = find(splited_att==att);
                        xdata = app.map.xdata{DesignatedCol}{N}(:,idx);    % double
                        if xMax<max(xdata), xMax = max(xdata); end
                        if xMin>min(xdata), xMin = min(xdata); end

                        ydata = app.map.ydata{DesignatedCol}{N}(:,idx);    % double
                        if yMax<max(ydata), yMax = max(ydata); end
                        if yMin>min(ydata), yMin = min(ydata); end

                        if zFlag
                            zdata = app.map.zdata{DesignatedCol}{N}(:,idx); % double
                            if zMax<max(zdata), zMax = max(zdata); end
                            if zMin>min(zdata), zMin = min(zdata); end
                        end
                        if att=="r" && N==1 % referenceは1回目だけplotする
                            line = '--';
                            LW = app.settings.LW*2/3; % ref用は少し細くする
                            color = "k";
                            plegend = [plegend; "Reference"];
                            if zFlag,   u = plot3(ax, xdata,ydata,zdata, 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            else,       u = plot(ax, xdata,ydata, 'LineWidth',LW, 'LineStyle',line, 'Color',color); end
                        elseif att=="s" || att=="e" || att=="p" || att==""
                            if att=="s",line = ':';
                            else,       line = '-'; end
                            LW = app.settings.LW;
                            color = app.logger.color{N};
                            if zFlag,   u = plot3(ax, xdata,ydata,zdata, 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            else,       u = plot(ax, xdata,ydata, 'LineWidth',LW, 'LineStyle',line, 'Color',color); end
                        end
                    end
                end
                if app.mode=="subfig",      plegend = [plegend; app.logger.lgdName.subfig];
                elseif app.mode=="onefig",  plegend = [plegend; "TODO"]; end
                u.Color = [u.Color(1:3), app.logger.alpha{N}];
                hold off;
                axis(ax, 'equal');
                grid on;
                if app.settings.fminor, grid minor; end

                margin = app.settings.margin;
                xmargin = (xMax-xMin)*margin;
                xlim([xMin-xmargin, xMax+xmargin]);
                ymargin = (yMax-yMin)*margin;
                ylim([yMin-ymargin, yMax+ymargin]);
                if zFlag
                    zmargin = (zMax-zMin)*margin;
                    zlim([zMin-zmargin, zMax+zmargin]);
                end

                set(gca, 'FontSize', app.settings.axsFS); % 軸のFontSize設定を先にしないとlabelが上書きされちゃう
                xlabel(xlabels, 'FontSize',app.settings.labFS, 'Interpreter','latex');
                ylabel(ylabels, 'FontSize',app.settings.labFS, 'Interpreter','latex');
                if zFlag
                    zlabel(zlabels, 'FontSize',app.settings.labFS, 'Interpreter','latex');
                    view(ax, app.settings.angle, app.settings.elevetorAngle); % 3Dプロット時の視点位置
                end

                if app.settings.flegend
                    lgd = legend(plegend, 'FontSize',app.settings.lgdFS, 'Orientation','horizontal');
                    lgd.NumColumns = 3; % TODO: app.Num, 入力されたlgdName, app.settings.lgdFSからいい感じに動的に変更したい
                    lgd.Layout.Tile = 'north'; % TODO: GUIで指定できるようにする？ app.settings.lgdPos
                end
            else % TODOTODOTODO: 引数に指定が無い場合
                columnNum = size(app.map, 1);
                for col = 1:columnNum
                    target = app.map.target{col};     % 'char'
                    splited_att = app.map.splited_att{col}; % "string" array
                    xlabels = app.map.xlabel{col};    % 'char' in {cell}
                    ylabels = app.map.ylabel{col};    % 'char' in {cell}
                    zlabels = app.map.zlabel{col};    % 'char' in {cell}
                    figNum = app.map.fignum{col};     % double
    
                    ydataSeparator = cellfun(@(C) size(C,2), app.map.ydata{col}) / length(splited_att); % [array]
                    % ydataの区切りでも使える

                    if ydataSeparator(1)==1 && contains(target,'-')
                        % "p1-p2"や"p1-p2-p3"用
                        app.onefig_plot(col);
                        continue; % TODO: これでいける？？ループしないか注意！！！！！(2026/01/19)
                    end

                    figure(figNum);
                    f.Name = clearn_target;
                    clf;
                    tiledlayout(1,1);
                    ax = nexttile(1);
                    plegend = {};
                    hold on;
                    for N=1:app.Num
                        for att = splited_att
                            idx = find(splited_att==att);
                            xdata = app.map.xdata{col}{N};                  % double
                            ydata = app.map.xdata{col}{N}(:, idx*ydataSeparator(N)-ydataSeparator(N)+1:idx*ydataSeparator(N)); % double
                            if att=="r" && N==1 % referenceは1回目だけplotする
                                line = '--';
                                LW = app.settings.LW*2/3; % ref用は少し細くする
                                color = "k";
                                plegend = [plegend; "Reference"]; % TODO: この処理じゃダメ
                                u = plot(ax, xdata,ydata, 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            elseif att=="s" || att=="e" || att=="p" || att==""
                                if att=="s",line = ':';
                                else,       line = '-'; end
                                LW = app.settings.LW;
                                color = app.logger.color{N};
                                u = plot(ax, xdata,ydata, 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            end
                        end
                        % TODO: legendをどうにかしてから付け足す
                    end
                end
            end
        end

        function subfig_plot(app)
            columnNum = size(app.map, 1);
            for col = 1:columnNum
                target = app.map.target{col};     % 'char'
                clearn_target = app.map.clearn_target{col}; % 'char'
                splited_att = app.map.splited_att{col}; % "string" array
                xlabels = app.map.xlabel{col};    % 'char' in {cell}
                ylabels = app.map.ylabel{col};    % 'char' in {cell}
                zlabels = app.map.zlabel{col};    % 'char' in {cell}
                figNum = app.map.fignum{col};     % double

                subfigNum = app.map.subfigColumnNum{col};
                % subfigNum = cellfun(@(C) size(C,2), app.map.ydata{col}) / length(splited_att); % [array]
                % ydataの区切りでも使える

                if subfigNum==1 && contains(target,'-')% ydataが1plotセット分しかない時 ex) "p1-p2","p1-p2-p3"
                    app.onefig_plot(col);
                    continue;
                end
                
                f = figure(figNum);
                f.Name = clearn_target;
                clf;
                app.adjust_figure_position(f);
                

                % ----------- プロット -----------
                tiledlayout(subfigNum,1);
                for figColumn = 1:subfigNum
                    ax = nexttile(figColumn);
                    plegend = {};
                    hold on;
                    yMax = -Inf;
                    yMin = Inf;
                    figHandle = [];
                    for N = 1:app.Num
                        xdata = app.map.xdata{col}{N}; % double
                        for att = splited_att
                            idx = find(splited_att==att);
                            ydata = app.map.ydata{col}{N}(:, idx*subfigNum-subfigNum+1:idx*subfigNum); % double
                            if yMax<max(ydata(:,figColumn)), yMax = max(ydata(:,figColumn)); end
                            if yMin>min(ydata(:,figColumn)), yMin = min(ydata(:,figColumn)); end
                            if att=="r" && N==1 % referenceは1回目だけplotする
                                line = '--';
                                LW = app.settings.LW*2/3; % ref用は少し細くする
                                color = "k";
                                plegend = [plegend; "Reference"];
                                u = plot(ax, xdata,ydata(:,figColumn), 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            elseif att=="s" || att=="e" || att=="p" || att==""
                                if att=="s",line = ':';
                                else,       line = '-'; end
                                LW = app.settings.LW;
                                color = app.logger.color{N};
                                u = plot(ax, xdata,ydata(:,figColumn), 'LineWidth',LW, 'LineStyle',line, 'Color',color);
                            end
                        end
                        u.Color = [u.Color(1:3), app.logger.alpha{N}];
                        if app.logger.lgdName.subfig{N} == "" % legendNameが空の場合
                            u.Annotation.LegendInformation.IconDisplayStyle = 'off';
                        else % legendNameが存在する場合
                            figHandle(end+1) = u; % figハンドルを追加
                            plegend = [plegend; app.logger.lgdName.(app.mode){N}];
                        end
                    end
                    hold off;
                    grid on;
                    if app.settings.fminor, grid minor; end

                    minTime = min(cellfun(@(c) c(1), app.data.time));
                    maxTime = max(cellfun(@(c) c(end), app.data.time));
                    xlim([minTime, maxTime]);

                    ymargin = (yMax-yMin)*app.settings.margin;
                    ylim([yMin-ymargin, yMax+ymargin]);
                    set(gca, 'FontSize', app.settings.axsFS); % 軸のFontSize設定を先にしないとlabelが上書きされちゃう
                    ylabel(ylabels{figColumn}, 'FontSize',app.settings.labFS, 'Interpreter','latex');
                    if figColumn==1 && app.settings.flegend
                        lgd = legend(plegend, 'FontSize',app.settings.lgdFS, 'Orientation','horizontal');
                        lgd.NumColumns = 3; % TODO: app.Num, 入力されたlgdName, app.settings.lgdFSからいい感じに動的に変更したい
                        lgd.Layout.Tile = 'north'; % TODO: GUIで指定できるようにする？ app.settings.lgdPos
                    end
                    if figColumn==subfigNum
                        xlabel(xlabels, 'FontSize',app.settings.labFS, 'Interpreter','latex');
                    end
                end
            end
        end

        function calc_rmse(app)
            val = ["px [m]"; "py [m]"; "pz [m]"; "vx [m/s]"; "vy [m/s]"; "vz [m/s]"];
            RMSEtab = table('RowNames',val);
            for N = 1:app.Num
                if app.logger.lgdName.subfig{N}~="", lgdName = app.logger.lgdName.subfig{N};
                else, lgdName = string(N); end
                rmseVal = [ rmse(app.data.p.est{N}, app.data.p.ref{N})';...
                            rmse(app.data.v.est{N}, app.data.v.ref{N})'];
                % rmseVal = compose("%.4f", rmseVal);
                rmseVal = round(rmseVal, 4); % 小数第5桁を四捨五入
                RMSEtab.(lgdName) = rmseVal;
            end

            disp('RMSE value');
            disp(RMSEtab); % TODO: uiで表示？

            origFunc = dbstack;
            if contains(origFunc(2).name, 'SavefigButtonPushed') && app.save.fRMSEoutput
                path = fullfile(app.save.folder, app.save.name);
                fullpath4rmse = [path, '_RMSE.xlsx'];
                RMSEtab.Properties.DimensionNames{1} = 'target';
                writetable(RMSEtab, fullpath4rmse, 'WriteRowNames', true);
                title = "";
                msg = "RMSE値はExcelへ正常に保存されました。\n\n" + ...
                    "The RMSE values saved successfully in the Excel file.";
                uiconfirm(app.UIFigure, sprintf(msg), title, ...
                    "Options",["OK"],...
                    "Icon","success");
            end
        end


        function calc_control_cost(app)
            R = diag([1, 1, 1, 1]);
            val = ["T"; "tau_roll"; "tau_pitch"; "tau_yaw";...
                "T_ave"; "tau_roll_ave"; "tau_pitch_ave"; "tau_yaw_ave"];
            uCosttab = table('RowNames',val);
            for N = 1:app.Num
                if app.logger.lgdName.subfig{N}~="", lgdName = app.logger.lgdName.subfig{N};
                else, lgdName = string(N); end
                u = app.data.u{N};
                uCostVal = trapz(app.data.time{N}, (u*R).*u );
                uCostAve = uCostVal / (app.data.time{N}(end) - app.data.time{N}(1));
                uCosttab.(lgdName) = [uCostVal'; uCostAve'];
            end

            disp('Control cost');
            disp(uCosttab);

            origFunc = dbstack;
            if contains(origFunc(2).name, 'SavefigButtonPushed') && app.save.fControlCost
                path = fullfile(app.save.folder, app.save.name);
                fullpath4rmse = [path, '_CotrolCost.xlsx'];
                uCosttab.Properties.DimensionNames{1} = 'target';
                writetable(uCosttab, fullpath4rmse, 'WriteRowNames', true);
                title = "";
                msg = "制御コスト値はExcelへ正常に保存されました。\n\n" + ...
                    "The control cost values saved successfully in the Excel file.";
                uiconfirm(app.UIFigure, sprintf(msg), title, ...
                    "Options",["OK"],...
                    "Icon","success");
            end
        end
    end
    
    methods (Access = private)
        
        function Register_target(app, title, prompt, iniInputs, fEdit)
            arguments
                app 
                title       string
                prompt      (1,7) cell
                iniInputs   (1,7) cell
                fEdit       logical
            end

            h = 1;
            fieldsize = [h 40; h 30; h 60; h 60; h 60; h 60; h 30];
            loop = 1;
            while true
                if loop==1, inputs = inputdlg(prompt, title, fieldsize, iniInputs);
                else,       inputs = inputdlg(prompt, title, fieldsize, inputs); end
                loop = loop + 1;
                if isempty(inputs) % X&キャンセル対策
                    disp("Canceled by the user.");
                    return;
                end
                if isempty(inputs{1}) % targetが入力されていない場合
                    title = "";
                    msg = "targetが入力されていませんでした。再入力しますか?\n\n" + ...
                        "The target was not entered. Would you like to enter again?";
                    selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                    if strcmp(selection, 'OK'), continue; % whileループの先頭に戻る
                    else, return; end
                end
    
                target = inputs{1};
                att = inputs{2};
                if contains(target, ',') || contains(att, ',') % target or att にカンマ','を含む場合
                    fcomma = true;
                    splited_target  = strsplit(target, ',');
                    splited_att     = strsplit(att, ',');
                    if length(splited_target) ~= length(splited_att) % 要素数が一致しない場合
                        title = "";
                        msg = "target: " + string(target) + "\n" + ...
                            "att: " + string(att) + "\n\n" + ...
                            "これらの要素数が一致しません。再入力しますか？\n\n" + ...
                            "The number of elements does not match. Would you like to enter again?";
                        selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                        if strcmp(selection, 'OK'), continue; % whileループの先頭に戻る
                        else, return; end
                    end
                    for i = 1:length(splited_att)
                        splited_target{i} = strrep(splited_target{i}, ' ',''); % スペースを削除
                        splited_att{i} = unique(strrep(splited_att{i}, ' ',''), 'stable'); % スペースを削除 + 'eer'のような重複を無くす
                    end
                    target = strjoin(splited_target, ','); % カンマで再結合
                    att = strjoin(splited_att, ',');
                else
                    fcomma = false;
                    target = strrep(target, ' ',''); % スペースを削除
                    att = unique(strrep(att, ' ',''), 'stable'); % スペースを削除 + 'eer'のような重複を無くす
                end

                if ~fEdit % AddtargetButtonPushedの場合
                    % ----------- 既存targetと入力したtarget の重複確認 -----------
                    isSame = strcmp(app.UITable.Data(:,2), target);
                    if any(isSame) % targetが既にあるものと重複している場合
                        title = "";
                        msg = "入力されたtarget: " + string(target) + "は既に存在しています。再入力しますか?\n\n" + ...
                            "The entered target: " + string(target) + "is already existing. Would you like to enter again?";
                        selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                        if strcmp(selection, 'OK'), continue; % whileループの先頭に戻る
                        else, return; end % 関数を抜ける = キャンセル
                    end
                end

                % ----------- att の違反確認 -----------
                fExp = zeros(app.Num,1);
                for N = 1:app.Num
                    fExp(N) = app.logger.LOGGER{N}.fExp;
                end
                if ~any(fExp),  allowed = 'pers'; % 全てSimデータの場合 = p(plant)使用可能
                else,           allowed = 'ers'; end % １つでもExpデータがある場合

                if fcomma % attにカンマを含む場合
                    for i = 1:length(splited_att)
                        if ~isempty(regexp(string(splited_att{i}), ['[^',allowed,']'], 'once'))
                            title = "";
                            msg = "入力されたatt: " + string(att) + "の内" + string(splited_att{i}) + "は使用不可能です。\n" + ...
                                "The entered att: " + string(splited_att{i}) + " in " + string(att) + " is invalid. \n\n" + ...
                                "再入力しますか?  Would you like to enter again?\n" + ...
                                "使用可能att: " + string(allowed);
                            selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                            if strcmp(selection, 'OK'), continue; % whileループの先頭に戻る
                            else, return; end % 関数を抜ける
                        end
                    end
                else
                    if ~isempty(regexp(string(att), ['[^',allowed,']'], 'once'))
                        title = "";
                        msg = "入力されたatt: " + string(att) + "は使用不可能です。\n" + ...
                            "The entered att: " + string(att) + " is invalid. \n\n" + ...
                            "再入力しますか?  Would you like to enter again?\n" + ...
                            "使用可能att: " + string(allowed);
                        selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                        if strcmp(selection, 'OK'), continue; % whileループの先頭に戻る
                        else, return; end % 関数を抜ける
                    end
                end


                % ----------- target の中に".result."がある場合の違反確認 -----------
                % TODO: controller.result.nominal_p1-controller.result.nominal_p2 <- このような場合には許容するようにしたい
                % "-", ":"がある場合の例外処理が必要
                % TODO: fcommaに対応させる
                isValid = true; % 違反検知フラグ
                if contains(target, ".result.")
                    if contains(target, "-") % "-"がある場合の例外処理
                        targets = strsplit(target, '-'); % バー"-"で区切る
                        targets = cellfun(@(C) regexprep(C, '\d', ''), targets); % ".result.ooo", oooのアルファベット部を抽出
                        for tag = targets
                            tmp = strsplit(tag, '.'); % ドット'.'で区切る
                            % TODO: if contains(target, "-"), else以下の処理を記述すれば良きだが冗長だよね
                        end
                    elseif contains(target, ":") % ":"がある場合の例外処理
                    else
                        tmp = strsplit(target, '.'); % ドット'.'で区切る
                        tmpLetter = regexprep(tmp{3}, '\d', ''); % ".result.ooo", oooのアルファベット部を抽出
                        % tmpNumber = str2double(regexprep(tmp{3}, '\D', '')); % ".result.ooo", oooの数字部分を抽出
                        for N = 1:app.Num
                            if ~isfield(app.logger.LOGGER{N}.Data.agent, tmp{1})
                                title = "入力されたtargetに対する警告  Warning for the entered target.";
                                msg = "[.result.] を検知しましたが [" + string(tmp{1}) + "] が適合しません。\n\n" + ...
                                    "[.result.] was detected, but [" + string(tmp{1}) + "] does not match.\n\n\n" + ...
                                    "再入力しますか?  Would you like to enter again?";
                                selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                                if strcmp(selection, 'OK')
                                    isValid = false;
                                    break;
                                else
                                    return;
                                end
                            end
                            if ~isfield(app.logger.LOGGER{N}.Data.agent.(tmp{1}).(tmp{2}){end}, tmpLetter)
                                title = "入力されたtargetに対する警告  Warning for the entered target.";
                                msg = "target: " + string(target) + "は以下のLOGGERに含まれていないので使用できません。\n" + ...
                                    "target: " + string(target) + "is not included in the following LOGGER, so cannot be used.\n\n" + ...
                                    "LOGGER: " + string(app.filenames{N}) + "\n\n" + ...
                                    "再入力しますか?  Would you like to enter again?";
                                selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                                if strcmp(selection, 'OK')
                                    isValid = false;
                                    break;
                                else
                                    return;
                                end
                            end
                        end
                        att = ''; % attは自動的に''とする
                    end
                end

                % 違反があるかどうかのチェック
                if isValid, break; % whileループを抜ける
                else,       continue; end
            end
            
            % 2列目=attの内 'r'がある場合、'r'が先頭になるよう変換 ex) 'er' -> 're'
            
            if fcomma
                for i = 1:length(splited_att)
                    if ~isempty(splited_att{i}), splited_att{i} = regexprep(splited_att{i}, '(.*)r(.*)', 'r$1$2'); end
                end
                att = strjoin(splited_att, ',');
            else
                if ~isempty(att), att = regexprep(att, '(.*)r(.*)', 'r$1$2'); end
            end
            inputs{1} = target; inputs{2} = att;
            if fEdit % EditselectedtargetButtonPushedの場合
                isSelected = strcmp(app.UITable.Data(:,2), app.TargetDropDown.Value);
                app.UITable.Data(isSelected, :) = [{true}, inputs']; % 選択されたtargetを入力結果で上書き
                app.LoadsettingsButton.FontColor = app.RedColor;
                app.IsPlotSetting = true;
            else % AddtargetButtonPushedの場合
                app.UITable.Data = [app.UITable.Data; {false}, inputs']; % 新しい行にinputsを追加
            end
            app.TargetDropDown.Items = unique(string(app.UITable.Data(:,2)), 'stable'); % DropDownを更新
        end
        
        function adjust_figure_position(app, f)
            ppi = get(0, 'ScreenPixelsPerInch');
            figSize = (app.settings.cmFigSize / 2.54) * ppi; % figsizeの [cm]->[pixel]への単位変換
            offsetX = 60;   % 階段の横ズレ
            offsetY = 50;   % 階段の縦ズレ
            margin = 80;    % タスクバー等の余白用
            columnWidth = figSize(1)*0.8; % 列を切り替えた際にどれぐらい横に進むか

            % 暫定位置の計算
            targetX = app.currentStartX + (app.colCount * offsetX);
            targetY = (app.scrH - figSize(2) - margin) - (app.colCount * offsetY);

            % 折り返し判定
            if (targetY < margin) || (targetX + figSize(1) > app.scrW)
                app.currentStartX = app.currentStartX + columnWidth;
                app.colCount = 0; % リセット
                
                % 再計算
                targetX = app.currentStartX;
                targetY = app.scrH - figSize(2) - margin;

                % 画面右端リセット
                if (targetX + figSize(1) > app.scrW)
                    app.currentStartX = figSize(1)*0.5;
                    targetX = figSize(1)*0.5;
                end
            end

            f.Position = [targetX, targetY, figSize(1), figSize(2)];
            app.colCount = app.colCount + 1; % 次回のためにカウントアップ
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %---- GUI関連の設定 ----%
            app.UIFigure.Name = "PlotGUI";
            app.PlotmodeSwitch.Items = ["subfig", "onefig"];
            app.PlotmodeSwitch.Value = app.mode;
            app.UITable.ColumnFormat = {'logical', [], [], [], [], [], [], 'numeric'}; % use,target,att,xlabel,ylabel,zlabel,legend, agentID
            dummy_lgd = {""};
            app.tableData.subfig = default_plot_settings4table("subfig", dummy_lgd);
            app.tableData.onefig = default_plot_settings4table("onefig");
            app.UITable.Data = app.tableData.(app.mode);
            app.TargetDropDown.Items = unique(string(["target";app.UITable.Data(:,2)]), 'stable');
            % app.UITable.Interpreter = 'latex'; % TODO: いつか対応してくれたら嬉しみ 2026/1/4
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.PlotButton.FontColor    = app.GrayColor;
            app.SavefigButton.FontColor = app.GrayColor;

            %---- 自作クラス関連の設定 ----%
            app.filepathes  = {};
            app.filenames   = {};

            app.logger.LOGGER   = {};
            app.logger.alpha    = {};
            app.logger.color    = {};
            app.logger.lgdName.onefig  = {};
            app.logger.lgdName.subfig  = {};

            app.settings.tRange = [app.MintimeSpinner.Value, app.MaxtimeSpinner.Value];
            app.settings.phase  = "f";
            app.settings.LW     = app.LineWidthSpinner.Value;
            app.settings.axsFS  = app.AxisFontSizeSpinner.Value;
            app.settings.labFS  = app.LabelFontSizeSpinner.Value;
            app.settings.lgdFS  = app.LegendFontSizeSpinner.Value;
            app.settings.fcolor = app.PhasecolorflagCheckBox.Value; % false
            app.settings.fminor = app.MinorgridCheckBox.Value; % true
            app.settings.flegend= app.ShowlegendCheckBox.Value; % true
            app.settings.margin = 0.1; % プロットした時の余白割合0~1
            app.settings.angle  = -120; % 3Dプロット時の方位角（おおよそ実験時の撮影場所）
            app.settings.elevetorAngle = 25; % 3Dプロット時の迎角（おおよそ実験時の撮影場所）
            app.settings.cmFigSize = [14.8167   11.1125]; % figureの[縦幅, 横幅] [cm]

            app.data.time   = {};
            app.data.idx    = {};
            app.data.p      = {}; % RMSE用
            app.data.v      = {}; % RMSE用
            app.data.u      = {}; % 制御コスト用

            app.save.folder = app.CurrentsavefolderEditField.Value; % char
            app.save.name   = ''; % char
            app.save.style  = app.SavestyleButtonGroup.SelectedObject.Text; % char
            app.save.fRMSEoutput = app.RMSEvaluesoutputCheckBox.Value; % false
            app.save.fControlCost= app.ControlcostoutputCheckBox.Value; % false


            set(0, 'Units', 'pixels');
            scrSize = get(0, 'ScreenSize'); % PCに応じたスクリーン幅を動的に取得
            app.scrW = scrSize(3);
            app.scrH = scrSize(4);
        end

        % Value changed function: PlotmodeSwitch
        function PlotmodeSwitchValueChanged(app, event)
            if app.PlotmodeSwitch.Value=="onefig" && numel(app.logger.LOGGER)>=2
                % "onefig"に変更時にLOGGERが2種類以上セットされている場合
                title = "非推奨!!  Not recommended!!";
                msg = "LOGGERが2種類以上セットされているonefigは非常に見にくいですが、modeを切り替えますか?\n\n" + ...
                    "A onefig with two or more LOGGERs set is very hard to read. Would you like to switch modes?";
                selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                if ~strcmp(selection, 'OK')
                    app.PlotmodeSwitch.Value = "subfig";
                    return; % OKじゃないときはモード切り替えしない
                end
            end
            app.mode = app.PlotmodeSwitch.Value; % modeの変更

            activeColor = [0, 1, 0]; % Green lamp
            inactiveColor = [0.94, 0.94, 0.94]; % White lamp
            if app.mode == "subfig"
                app.subfigLamp.Color = activeColor;
                app.onefigLamp.Color = inactiveColor;
                app.PhasecolorflagCheckBox.Visible = 'off';
            elseif app.mode == "onefig"
                app.subfigLamp.Color = inactiveColor;
                app.onefigLamp.Color = activeColor;
                app.PhasecolorflagCheckBox.Visible = 'on';
            end
            app.tableData.(app.mode) = set_plot_settings4table(app.tableData.(app.mode), app.mode, app.logger.lgdName.subfig);
            app.UITable.Data = app.tableData.(app.mode);

            app.TargetDropDown.Items = unique(string(app.UITable.Data(:,2)), 'stable'); % DropDownを更新
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Callback function: LinecolorColorPicker
        function LinecolorColorPickerValueChanged(app, event)
            targetNodes = app.LoggerTree.SelectedNodes;
            if ~isempty(targetNodes)
                if isequal(targetNodes.Tag, 'children') % 子ノードを選択中の場合
                    targetNodes = targetNodes.Parent; % その親ノードに移動
                end
                newColor = app.LinecolorColorPicker.Value;
                iconSize = size(targetNodes(:).Icon, 1:2);
                iconData = repmat(reshape(newColor, [iconSize, 3]), [iconSize, 1]);

                for i = 1:length(targetNodes)
                    targetNodes(i).Icon = iconData;
                end
                app.IsLoggerSetting = true;
                app.SetLoggerButton.FontColor = app.RedColor;
            end
        end

        % Button pushed function: LoadaddmatfileLOGGERButton
        function LoadaddmatfileLOGGERButtonPushed(app, event)
            % TODO: クリックした通りの順番に登録されるようにしたい！！(2026/01/19時点ではuigetfile関数の機能として無理らしい)
            [filename, pathname] = uigetfile('*.mat', 'MATファイルを選択してください', 'MultiSelect','on');
            figure(app.UIFigure);
            if isequal(filename,0) % キャンセルされた場合の処理
                disp('Canceled by the user.');
                figure(app.UIFigure);
                return;
            end

            if ischar(filename), filename = {filename}; end
            iconSize = [1,1];
            defaultColor = [0.00, 0.45, 0.74];
            iconData = repmat(reshape(defaultColor, [1,1,3]), [iconSize, 1]);
            for i = 1:length(filename)
                currentFile = filename{i};
                currentPath = fullfile(pathname, currentFile);
                
                dummyAlpha = 1;
                dummyLegend = "";
                NodeDataStruct = struct('fullpath',currentPath, 'alpha',dummyAlpha, 'legendName',dummyLegend);
                % ツリーにノードを追加
                newParent = uitreenode(app.LoggerTree, ...
                    'Text', currentFile, ...
                    'NodeData', NodeDataStruct, ...
                    'Icon', iconData, ...
                    'Tag', 'parent');
                uitreenode(newParent, 'Text',currentPath, 'Tag','children'); % app.LoggerTree.SelectedNodes.Children(1)
                uitreenode(newParent, 'Text',dummyLegend, 'Tag','children'); % (2)
                uitreenode(newParent, 'Text',num2str(dummyAlpha), 'Tag','children'); % (3)
                app.LoggerTree.SelectedNodes = newParent; % 最後に追加したノードを選択状態にする
            end
        end

        % Button pushed function: DeletecheckedLOGGERButton
        function DeletecheckedLOGGERButtonPushed(app, event)
            checkedNodes = app.LoggerTree.CheckedNodes;
            if ~isempty(checkedNodes)
                title = "削除確認  Delete confirmation";
                msg = "以下のLOGGERを削除しますか？\n" + ...
                    "Are you sure you want to delete below LOGGERs?\n" + ...
                    "---------------------------------------------------------------------\n";
                for i = 1:length(checkedNodes)
                    targetNode = checkedNodes(i);
                    if isequal(targetNode.Tag, 'parent')
                        msg = msg + string(checkedNodes(i).Text) + "\n\n";
                    end
                end
                selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                if strcmp(selection, 'OK')
                    delete(checkedNodes);
                end
            end
        end

        % Value changing function: TimerangeSlider
        function TimerangeSliderValueChanging(app, event)
            % TODO: スライダーのトグルを離した後にPlotsettingsPanelの背景をクリックすると、スライダーがリセットされるバグ(?)あり
            app.MintimeSpinner.Limits = app.TimerangeSlider.Limits;
            app.MaxtimeSpinner.Limits = app.TimerangeSlider.Limits;

            app.MintimeSpinner.Value = event.Value(1);
            app.MaxtimeSpinner.Value = event.Value(2);
            app.tmpRange = event.Value;
        end

        % Value changed function: TimerangeSlider
        function TimerangeSliderValueChanged(app, event)
            % スライダーを動かし終わった後の処理
            currentVal = app.tmpRange;
            timeSpinner_dt = 0.05;
            if isempty(currentVal), currentVal = app.TimerangeSlider.Value; end
            if currentVal(1) == currentVal(2) % スライダーの２値が一致している場合
                if currentVal(2)==app.TimerangeSlider.Limits(2) % スライダーを最大値いっぱいにしているとき
                    currentVal(1) = currentVal(1) - timeSpinner_dt;
                else
                    currentVal(2) = currentVal(2) + timeSpinner_dt; % timeSpinnerの間隔分足す
                end
                roundedVal = currentVal; % 四捨五入しない
            else
                roundedVal = round(currentVal); % 小数点を四捨五入する処理
            end
            app.TimerangeSlider.Value = roundedVal;
            app.MintimeSpinner.Limits(2) = roundedVal(2) - timeSpinner_dt; % MintimeSpinnerの上限値を設定
            app.MaxtimeSpinner.Limits(1) = roundedVal(1) + timeSpinner_dt; % MaxtimeSpinnerの下限値を設定
            app.MintimeSpinner.Value = roundedVal(1);
            app.MaxtimeSpinner.Value = roundedVal(2);
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Button pushed function: DeleteallLOGGERButton
        function DeleteallLOGGERButtonPushed(app, event)
            title = "削除確認  Delete confirmation";
            msg = sprintf('全Loggerを削除しますか?\n\nAre you sure you want to delete all LOGGERs?');
            selection = uiconfirm(app.UIFigure, msg, title);
            if strcmp(selection, 'OK')
                delete(app.LoggerTree.Children);
            end
        end

        % Value changed function: LegendnameEditField
        function LegendnameEditFieldValueChanged(app, event)
            legendName = app.LegendnameEditField.Value;
            targetNodes = app.LoggerTree.SelectedNodes;

            if ~isempty(targetNodes) && numel(targetNodes)==1
                if isequal(targetNodes.Tag, 'children') % 子ノードを選択中の場合
                    targetNodes = targetNodes.Parent; % その親ノードに移動
                end
                targetNodes.NodeData.legendName = string(legendName); % NodeData.legendNameの構造体に代入
                targetNodes.Children(2).Text = legendName; % 表示用にNodeのChildren(2)に上書き
                app.IsLoggerSetting = true;
                app.SetLoggerButton.FontColor = app.RedColor;
            end
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            currentAlpha = app.AlphaSpinner.Value;
            targetNodes = app.LoggerTree.SelectedNodes;

            if ~isempty(targetNodes) && numel(targetNodes)==1
                if isequal(targetNodes.Tag, 'children') % 子ノードを選択中の場合
                    targetNodes = targetNodes.Parent; % その親ノードに移動
                end
                targetNodes.NodeData.alpha = currentAlpha; % NodeData.alphaの構造体に代入
                targetNodes.Children(3).Text = num2str(currentAlpha); % 表示用にNodeのChildren(3)に上書き
                app.IsLoggerSetting = true;
                app.SetLoggerButton.FontColor = app.RedColor;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if app.IsPlotSetting == true
                title = "";
                msg = "設定の変更が検知されました。loadしますか?\n\n" + ...
                    "A setting change has been detected. Do you want to load?";
                opt = ["Load & Plot", "Plot"];
                selection = uiconfirm(app.UIFigure, sprintf(msg), title, Options=opt);
                if strcmp(selection, 'Load & Plot'), app.LoadsettingsButtonPushed(); end
            end
            app.currentStartX = 50;
            app.colCount = 0;
            if app.mode == "subfig"
                app.subfig_plot();
            elseif app.mode == "onefig"
                app.onefig_plot();
            end

            app.calc_rmse();
            app.calc_control_cost();

            % TODO: saveするfigを２つのスピナーで指定できるようにする場合、
            % スピナーの値をapp.map{:,11}=fignumに応じて動的に変更する。
            % ここの時点で存在しているfigureオブジェクトに応じて、指定できる値は変える。
        end

        % Button down function: PlotsettingsPanel
        function PhaseUpdateCheckboxes(app, event)
            % Value changed function: Arming, Takeoff, Flight, LandingCheckBox
            cbs = [app.ArmingCheckBox, app.TakeoffCheckBox, app.FlightCheckBox, app.LandingCheckBox];
            chars = ["a", "t", "f", "l"];
            vals = [cbs.Value]; % 現在どのチェックボックスがONになっているか論理配列を取得
            onIndices = find(vals); % ONになっているインデックスを取得
            if length(onIndices) >= 2
                % 最初と最後のONのインデックスを特定
                firstIdx = min(onIndices);
                lastIdx = max(onIndices);

                for i = firstIdx:lastIdx % その間のチェックボックスをすべてON（true）にする
                    cbs(i).Value = true;
                end
                vals = [cbs.Value];
            end

            selectedChars = chars(vals == 1);
            app.settings.phase = strjoin(selectedChars, "");

            if ~isequal(app.settings.phase,"a") && ~isequal(app.settings.phase,"")
            % if ~isequal(app.settings.phase,"")
            % TODO:ほんとはこっちが良い。armingも指定できるように。でも現状のLOGGER的に無理そう...2026/1/5
                app.extract_time();
            end
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Value changed function: ArmingCheckBox
        function ArmingCheckBoxValueChanged(app, event)
            app.PhaseUpdateCheckboxes();
        end

        % Value changed function: TakeoffCheckBox
        function TakeoffCheckBoxValueChanged(app, event)
            app.PhaseUpdateCheckboxes();
        end

        % Value changed function: FlightCheckBox
        function FlightCheckBoxValueChanged(app, event)
            app.PhaseUpdateCheckboxes();
        end

        % Value changed function: LandingCheckBox
        function LandingCheckBoxValueChanged(app, event)
            app.PhaseUpdateCheckboxes();
        end

        % Button pushed function: SetLoggerButton
        function SetLoggerButtonValueChanged(app, event)
            checkedNodes = app.LoggerTree.CheckedNodes;
            if isempty(checkedNodes)
                title = "Set LOGGER alert";
                msg = sprintf('[LOGGER list]の中でLOGGERにチェックマークが付いていません。\n\nThe checkmark is not selected for the LOGGER in the [LOGGER list].');
                uialert(app.UIFigure, msg, title);
                app.IsAuthorized = false;
                return;
            end


            isParent = strcmp({checkedNodes.Tag}, 'parent');
            % TODO:checkを付けた順に登録されるので、上から順に登録されるようにする？？
            parentNodes = checkedNodes(isParent);

            if app.IsLoggerSetting == true % 変更を検知した場合
                app.SetLoggerButton.FontColor = app.GrayColor;
                app.Num = length(parentNodes);
                app.filepathes  = cell(app.Num, 1);
                app.filenames   = cell(app.Num, 1);

                app.logger.LOGGER   = cell(app.Num, 1);
                app.logger.alpha    = cell(app.Num, 1);
                app.logger.color    = cell(app.Num, 1);
                app.logger.lgdName.(app.mode)  = cell(app.Num, 1);
                app.loadingLabel.Visible = 'on';
                app.loadingLabel.Text = sprintf('loading...  %d/%d',0,app.Num);
                drawnow;
                for N = 1:app.Num
                    app.filepathes{N}   = parentNodes(N).NodeData.fullpath;
                    app.filenames{N}    = parentNodes(N).Text;
                    app.logger.LOGGER{N}    = LOGGER(parentNodes(N).NodeData.fullpath);
                    app.logger.alpha{N}     = parentNodes(N).NodeData.alpha;
                    app.logger.color{N}     = reshape(parentNodes(N).Icon, 1,3);
                    app.logger.lgdName.(app.mode){N}   = parentNodes(N).NodeData.legendName;
                    app.loadingLabel.Text = sprintf('loading...  %d/%d',N,app.Num);
                    drawnow;
                end
                % TODO4multi: agentIDの取り出し? app.logger.LOGGER{N}.targetで取ってこれる。
                app.extract_time();
                if app.IsAuthorized == false % 初回のloadのみdefault設定を行う
                    % TODO: 新しくloadした時に、使えないtargetが入っていたら困るかも...？
                    app.tableData.(app.mode) = default_plot_settings4table(app.mode, app.logger.lgdName.(app.mode)); % Tableをdefault設定に変更
                    app.UITable.Data = app.tableData.(app.mode);
                else
                    app.tableData.(app.mode) = set_plot_settings4table(app.UITable.Data, app.mode, app.logger.lgdName.(app.mode));
                    app.UITable.Data = app.tableData.(app.mode);
                    % 既存のUITable.Dataを使いながらLoggerTabの変更に基づいて変更する．
                end
                app.TargetDropDown.Items = unique(string(app.UITable.Data(:,2)), 'stable');


                app.loadingLabel.Visible = 'off';
                drawnow;
                app.LoadsettingsButton.FontColor = app.RedColor;
                app.IsPlotSetting = true;
                app.IsAuthorized = true; % app.logger.LOGGERに登録されたら
                app.IsLoggerSetting = false;
                app.TabGroup.SelectedTab = app.PlotSaveTab;
                % TODO: LOGGER読み込み後に、使えるtargetをtree形式で全て表示し、選択できるようにする。
                % 現状defaultで使っているtargetのlabelは勝手に入力する。それ以外はユーザーに入力させる。
                % target関連専用のtabGroup作っても良いかも...
            elseif app.IsLoggerSetting == false % 変更が無い場合
                fprintf('変更された設定はありません。\nThere are not changed settings.\n\n');
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            newTab = event.NewValue;
            oldTab = event.OldValue;
            if newTab == app.PlotSaveTab
                if ~app.IsAuthorized
                    title = "Tab change alert";
                    msg = sprintf('LOGGERがセットされていません。[LOGGER list]にチェックマークを入れて、[Set LOGGER]を押してください。\n\nThe LOGGER is not set. Check the box next to the logger in the [LOGGER list] and press [Set LOGGER].');
                    uialert(app.UIFigure, msg, title)
                    app.TabGroup.SelectedTab = oldTab;
                end
            end
        end

        % Callback function: LoggerTree
        function LoggerTreeCheckedNodesChanged(app, event)
            if mod(numel(event.CheckedNodes),4) == 0 % 親ノードを操作した場合
            else % 子ノードを操作した場合
                if numel(event.LeafCheckedNodes) > numel(event.PreviousLeafCheckedNodes)
                    % 子ノードにチェックを付けた場合、その親ノードに丸ごとチェックを付ける
                    app.LoggerTree.CheckedNodes = [app.LoggerTree.CheckedNodes; event.IndeterminateCheckedNodes];

                else % 子ノードのチェックを外した場合、チェックを付けなおす
                    app.LoggerTree.CheckedNodes = event.PreviousCheckedNodes;
                end
            end

            if numel(app.LoggerTree.CheckedNodes) == 0
                app.IsLoggerSetting = false;
                app.SetLoggerButton.FontColor = app.GrayColor;
            else
                app.IsLoggerSetting = true;
                app.SetLoggerButton.FontColor = app.RedColor;
            end
        end

        % Value changed function: MintimeSpinner
        function MintimeSpinnerValueChanged(app, event)
            MinTime = app.MintimeSpinner.Value;
            app.TimerangeSlider.Value(1) = MinTime;
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Value changed function: MaxtimeSpinner
        function MaxtimeSpinnerValueChanged(app, event)
            MaxTime = app.MaxtimeSpinner.Value;
            app.TimerangeSlider.Value(2) = MaxTime;
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Value changed function: LineWidthSpinner
        function LineWidthSpinnerValueChanged(app, event)
            value = app.LineWidthSpinner.Value;
            app.settings.LW = value;
        end

        % Value changed function: LegendFontSizeSpinner
        function LegendFontSizeSpinnerValueChanged(app, event)
            value = app.LegendFontSizeSpinner.Value;
            app.settings.lgdFS = value;
        end

        % Value changed function: LabelFontSizeSpinner
        function LabelFontSizeSpinnerValueChanged(app, event)
            value = app.LabelFontSizeSpinner.Value;
            app.settings.labFS = value;
        end

        % Value changed function: AxisFontSizeSpinner
        function AxisFontSizeSpinnerValueChanged(app, event)
            value = app.AxisFontSizeSpinner.Value;
            app.settings.axsFS = value;
        end

        % Button pushed function: AddtargetButton
        function AddtargetButtonPushed(app, event)
            title = "Enter targets";
            if app.mode == "subfig"
                prompt = {'Target: ex) p    controller.result.ooo', ...
                    'Att: ex) er    (blank)', ...
                    'xlabel: ex) Time [s]    Time[s]', ...
                    'ylabel: ex) $x$[m],$y$[m],$z$[m]    $T$[N],$\tau_{roll}$[Nm],$\tau_{pitch}$[Nm],$\tau_{yaw}$[Nm]', ...
                    'zlabel: ex) (blank)    (blank)', ...
                    'legend: ex) HL-LQR,ooo', ...
                    'agentID: ex) 1'};
            elseif app.mode == "onefig"
                prompt = {'Target: ex) p    controller.result.ooo', ...
                    'Att: ex) er    (blank)', ...
                    'xlabel: ex) Time [s]    Time[s]', ...
                    'ylabel: ex) Position [m]    Input [N,Nm]', ...
                    'zlabel: ex) (blank)    (blank)', ...
                    'legend: ex) $x$est.,$y$est.,$z$est,$x$ref.,$y$ref.$z$ref.    $T$,$\tau_{roll}$,$\tau_{pitch}$,$\tau_{yaw}$', ...
                    'agentID: ex) 1'};
            end
            iniInputs = {'.result.', '', 'Time [s]', '', '', '', '1'};

            app.Register_target(title, prompt, iniInputs, false);
        end

        % Button pushed function: DeleteselectedtargetButton
        function DeleteselectedtargetButtonPushed(app, event)
            isSelected = strcmp(app.UITable.Data(:,2), app.TargetDropDown.Value); % 選択されたtarget(行)を特定
            title = "削除確認  Delte confirmation";
            msg = "以下のtargetを削除しますか？\n" + ...
                "Are you sure you want to delete below target?\n\n";
            msgtag = {"use: ", "target: ", "     att: ", "xlabel: ", "ylabel: ", "zlabel: "};
            for i = 2:6
                tmp = app.UITable.Data{isSelected,i};
                if ~isempty(tmp), tmp = strrep(tmp, '\', '\\'); end
                if isempty(tmp),msg = msg + msgtag{i} + "(blank)" + "\n";
                else,           msg = msg + msgtag{i} + string(tmp) + "\n"; end
            end
            selection = uiconfirm(app.UIFigure, sprintf(msg), title);
            if strcmp(selection, 'OK')
                app.UITable.Data = app.UITable.Data(~isSelected,:); % 削除
                app.TargetDropDown.Items = unique(string(app.UITable.Data(:,2)), 'stable'); % DropDownを更新
            end
        end

        % Button pushed function: EditselectedtargetButton
        function EditselectedtargetButtonPushed(app, event)
            % 選択されたtarget(行)を特定し、抽出
            isSelected = strcmp(app.UITable.Data(:,2), app.TargetDropDown.Value);
            currentTarget = app.UITable.Data(isSelected, 2:end);
                % 別ウィンドウ入力用設定
            title = sprintf("Edit target: %s", currentTarget{1});
            prompt = {'Target:', 'Att:', 'xlabel:', 'ylabel:', 'zlabel:', 'legend:', 'agentID:'};
            currentSetting = cell(1,length(prompt));
            for i = 1:length(prompt) % 空(0x0)の場合の処理
                if ~isempty(currentTarget{i}),  currentSetting{i} = string(currentTarget{i});
                else,                           currentSetting{i} = ""; end
            end
            
            app.Register_target(title, prompt, currentSetting, true);
        end

        % Value changed function: PhasecolorflagCheckBox
        function PhasecolorflagCheckBoxValueChanged(app, event)
            app.settings.fcolor = app.PhasecolorflagCheckBox.Value;
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            if isequal(app.settings.phase,"a") || isequal(app.settings.phase,"")
                % phaseが"a",""の時
                title = "Phase warning";
                msg = "phaseにチェックマークがついていません。\n\n" + ...
                    "The checkmark is not selected for phase.";
                uialert(app.UIFigure, sprintf(msg), title);
                return;
            end
            
            if app.IsPlotSetting==false
                title = "";
                msg = "変更された設定はありません。\n\n" + ...
                    "There are not changed settings.";
                uialert(app.UIFigure, sprintf(msg), title);
                return;
            end
            
            % チェックを付けた項目の取り出し
            isChecked = cell2mat(app.UITable.Data(:,1));
            tableCell = app.UITable.Data(isChecked,2:end); % 1列目のuseは除く


            % 空のcell配列に""を代入
            emptyIdx = cellfun(@isempty, tableCell);
            tableCell(emptyIdx) = {''};


            attCell = tableCell(:, 2);
            % attCell = regexprep(tableCell(:, 2), '(.*)r(.*)', 'r$1$2'); % 2列目=attの内 'r'がある場合、'r'が先頭になるよう変換 ex) 'er' -> 're'
            attCell = [attCell, cellfun(@(rats) (string(rats'))', attCell, 'UniformOutput', false)]; % attを分割し2列目に格納 ex) 're' -> "r", "e"


            % 3:5列目=labelをカンマ','で区切り、各cell配列に変換
            if app.mode=="subfig",      labelCell = cellfun(@(cell) strsplit(cell, ','), tableCell(:,3:5), 'UniformOutput',false);
            elseif app.mode=="onefig",  labelCell = tableCell(:,3:5); end


            % 7列目=agentIDを数値に変換
            agentIDCell = cellfun(@str2num, tableCell(:,7), 'UniformOutput', false); % やり方はちょいキモ
            tableCell(:,7) = agentIDCell;

            % ----------- data関連設定 -----------
            MinTime = app.MintimeSpinner.Value;
            MaxTime = app.MaxtimeSpinner.Value;
            app.extract_time("min",MinTime, "max",MaxTime);
            app.data.p.est = cell(app.Num, 1);
            app.data.p.ref = cell(app.Num, 1);
            app.data.v.est = cell(app.Num, 1);
            app.data.v.ref = cell(app.Num, 1);
            app.data.u     = cell(app.Num, 1);
            agentID = 1; % TODO4multi: 代入の仕方どうしよっかなー
            for N = 1:app.Num
                idx = app.data.idx{N};
                p_est = app.logger.LOGGER{N}.data(agentID, "p", "e", "phase",app.settings.phase);
                p_ref = app.logger.LOGGER{N}.data(agentID, "p", "r", "phase",app.settings.phase);
                v_est = app.logger.LOGGER{N}.data(agentID, "v", "e", "phase",app.settings.phase);
                v_ref = app.logger.LOGGER{N}.data(agentID, "v", "r", "phase",app.settings.phase);
                u = app.logger.LOGGER{N}.data(agentID, "input", "", "phase",app.settings.phase);

                app.data.p.est{N} = p_est(idx(1):idx(2),:);
                app.data.p.ref{N} = p_ref(idx(1):idx(2),:);
                app.data.v.est{N} = v_est(idx(1):idx(2),:);
                app.data.v.ref{N} = v_ref(idx(1):idx(2),:);
                app.data.u{N} = u(idx(1):idx(2),:);
            end

            % ----------- map関連設定 -----------
            columnNum = size(tableCell, 1);
            app.loadingLabel4settings.Visible = 'on';
            app.loadingLabel4settings.Text = sprintf('loading...  %d/%d',0,columnNum);
            drawnow;
            xdata = cellfun(@(C) cell(app.Num,1), cell(columnNum, 1), 'UniformOutput', false);
            ydata = cellfun(@(C) cell(app.Num,1), cell(columnNum, 1), 'UniformOutput', false);
            zdata = cellfun(@(C) cell(app.Num,1), cell(columnNum, 1), 'UniformOutput', false);
            subfigColumnNumCell = cell(columnNum,1); % subfigをプロットするときに何行必要かを入れる
            for col = 1:columnNum
                target  = tableCell{col,1};
                for N = 1:app.Num
                    if contains(target,'-') % targetに"-"を含んでいる場合 ex) "p1-p2"
                        % TODO: "p1-p2"のように2Dプロットを想定している場合と 引き算として"-"を使っている場合分けをしたい
                        % "数字"と"-"を取り除き
                        %   -> 要素が同じなら2Dプロット用
                        %   -> 異なるなら引き算としての処理を行う
                        splited_target = strsplit(target, '-');
                        for i = 1:length(splited_target)
                            token = regexp(splited_target{i}, '([a-zA-Z]+)(\d+)', 'tokens'); % ex) token{1}{1}=p, token{1}{2}=Number
                            tmp = [];
                            for att = attCell{col,2} % "r", "e"のように分かれる
                                Data = app.logger.LOGGER{N}.data(agentID, token{1}{1}, att, "phase",app.settings.phase);
                                Data = Data(app.data.idx{N}(1):app.data.idx{N}(2), str2double(token{1}{2}));
                                tmp = [tmp, Data];
                            end

                            if i==1,        xdata{col}{N} = tmp;
                            elseif i==2,    ydata{col}{N} = tmp;
                            elseif i==3,    zdata{col}{N} = tmp;
                            end
                        end

                    elseif contains(target,',') % カンマ','を含んでいる場合
                        splited_target  = strsplit(target, ',');
                        splited_att     = strsplit(attCell{col,2}, ',');
                        if length(splited_target)~=length(splited_att)
                            % targetとattの要素数が一致しない場合
                            title = "カンマ付きtargetの違反  Violation of target with comma";
                            msg = "target: " + string(target) + "\n" + ...
                                "att: " + string(att) + "\n\n" + ...
                                "要素数が一致していません。";
                            
                            selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                            if strcmp(selection, 'OK')
                                % TODO: 違反しているtargetを示してEditselectedtargetButtonPushedへ飛ばす
                                % この時、app.TargetDropDown.ValueをtableCell{col,1}に置き換える。charとstr注意
                            else, return;
                            end
                            return; % 上記TODO完了したら消す
                        end
                        for i = 1:length(splited_target)
                        end

                    elseif ~contains(target,':') && any(isstrprop(target, 'digit')) % コロン':'が無い && 数字を含んでいる場合
                        targetLetter = regexprep(target, '\d', '');
                        targetNumber = str2double(regexprep(target, '\D', ''));
                        xdata{col}{N} = app.data.time{N};
                        tmp = [];
                        for att = attCell{col,2} % "r", "e"のように分かれる
                            Data = app.logger.LOGGER{N}.data(agentID, targetLetter, att, "phase",app.settings.phase);
                            Data = Data(app.data.idx{N}(1):app.data.idx{N}(2), targetNumber);
                            tmp = [tmp, Data];
                        end
                        ydata{col}{N} = tmp;

                    else
                        xdata{col}{N} = app.data.time{N};
                        tmp = [];
                        for att = attCell{col,2} % "r", "e"のように分かれる
                            Data = app.logger.LOGGER{N}.data(agentID, target, att, "phase",app.settings.phase);
                            Data = Data(app.data.idx{N}(1):app.data.idx{N}(2), :);
                            tmp = [tmp, Data];
                        end
                        ydata{col}{N} = tmp;
                    end
                    
                    if app.mode=="subfig"
                        if contains(target,',') % カンマ','を含んでいる場合
                            subfigColumnNumCell{col} = length(strsplit(attCell{col,1}, ','));
                        else
                            ydata_len   = size(ydata{col}{N},2)/length(attCell{col,2});
                            ylabel_len  = length(labelCell{col,2});
                            if ydata_len~=ylabel_len
                                % ydataとylabelのサイズが一致していない場合
                                title = "yに関する警告  Warning regarding y";
                                msg = "target: " + target + "\n\n" + ...
                                    "ydataとylabelの長さが一致しません。\n" + ...
                                    "（大体、ylabelの要素が足りない）";
                                selection = uiconfirm(app.UIFigure, sprintf(msg), title);
                                if strcmp(selection, 'OK')
                                    % TODO: 違反しているtargetを示してEditselectedtargetButtonPushedへ飛ばす
                                    % この時、app.TargetDropDown.ValueをtableCell{col,1}に置き換える。charとstr注意
                                else, return;
                                end
                                return; % 上記TODO完了したら消す
                            end
                            subfigColumnNumCell{col} = ydata_len;
                        end
                    end
                end
                app.loadingLabel4settings.Text = sprintf('loading...  %d/%d',col,columnNum);
                drawnow;
            end

            allFigs = findall(0, 'Type', 'figure', 'HandleVisibility', 'on');
            if ~ isempty(allFigs),  lastFigNum = max([allFigs.Number]);
            else,                   lastFigNum = 0; end
            figNumCell = num2cell([lastFigNum+1 : lastFigNum+columnNum]');

            clearntargetCell = create_clearn_target(tableCell(:,1));

            if app.mode == "subfig"
                valNames = {'target', 'clearn_target', 'att', 'splited_att',...
                            'xlabel', 'ylabel', 'zlabel',...
                            'agentID', 'xdata', 'ydata', 'zdata',...
                            'fignum', 'subfigColumnNum'};
                app.map = table(tableCell(:,1), clearntargetCell', attCell(:,1), attCell(:,2), ...
                                labelCell(:,1), labelCell(:,2), labelCell(:,3), ...
                                agentIDCell, xdata, ydata, zdata,...
                                figNumCell, subfigColumnNumCell, ...
                                'VariableNames', valNames);
            elseif app.mode == "onefig"
                % 6列目=legendをカンマ','で区切り、各cell配列に変換
                legendCell = cellfun(@(cell) strsplit(cell, ',')', tableCell(:,6), 'UniformOutput',false);
                % TODO: att変更の順番に応じて変更しなきゃいけない...
                valNames = {'target', 'clearn_target', 'att', 'splited_att',...
                            'xlabel', 'ylabel', 'zlabel',...
                            'legend', 'agentID', 'xdata', 'ydata', 'zdata',...
                            'fignum'};
                app.map = table(tableCell(:,1), clearntargetCell, attCell(:,1), attCell(:,2), ...
                                labelCell(:,1), labelCell(:,2), labelCell(:,3), ...
                                legendCell, agentIDCell, xdata, ydata, zdata,...
                                figNumCell, ...
                                'VariableNames', valNames);
            end

            app.loadingLabel4settings.Visible = 'off';
            drawnow;
            app.LoadsettingsButton.FontColor = app.GrayColor;
            app.IsPlotSetting = false;
            app.PlotButton.FontColor = app.RedColor;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.LoadsettingsButton.FontColor = app.RedColor;
            app.IsPlotSetting = true;
        end

        % Selection changed function: SavestyleButtonGroup
        function SavestyleButtonGroupSelectionChanged(app, event)
            app.save.style = app.SavestyleButtonGroup.SelectedObject.Text;
            % TODO: 複数選択可能にしたい！
            % 例) 'pdf, fig' を選択中の時、pdf, figフォルダーを自動生成してその中に保存
        end

        % Button pushed function: SelectsavefolderButton
        function SelectsavefolderButtonPushed(app, event)
            pathname = uigetdir(app.save.folder, '保存するフォルダーを選択してください');
            figure(app.UIFigure);
            if isequal(pathname,0) % キャンセルされた場合の処理
                disp('Canceled by the user.');
                figure(app.UIFigure);
                return;
            end
            app.save.folder = pathname;
            app.CurrentsavefolderEditField.Value = pathname;
        end

        % Value changed function: SavenameEditField
        function SavenameEditFieldValueChanged(app, event)
            name = app.SavenameEditField.Value;
            % TODO: '\'など、ファイル構成時に違反になるものをこの時点で除外
            app.save.name = name;
            app.IsSaveSetting = true;
            app.SavefigButton.FontColor = app.RedColor;
        end

        % Button pushed function: SavefigButton
        function SavefigButtonPushed(app, event)
            if ~app.IsSaveSetting
                title = "Save settings alert";
                msg = "Save settingsの項目を全て埋めて下さい。\n\n" + ...
                    "Please fill in all the fields in the Save Settings section.";
                uialert(app.UIFigure, sprintf(msg), title);
                return;
            end

            path = fullfile(app.save.folder, app.save.name);
            [clearnTargets, ~] = create_clearn_target(app.map.target); % {cell} array
            for col = 1:length(clearnTargets)
                fullpath = [path, '_', clearnTargets{col}, '.', app.save.style];
                tmpFig = figure(app.map.fignum{col});
                % TODO: figureが存在していない時、plotし直す。
                %       app.subfig_plotの中でもDesignatedColを作るべき
                if isequal(app.save.style, 'fig')
                        savefig(tmpFig, fullpath);
                else,   exportgraphics(tmpFig, fullpath);
                end
            end
            app.calc_rmse();
            app.calc_control_cost();
            
            title = "";
            msg = "Figure は正常に保存されました。\n\n" + ...
                "The figure has been successfully saved.";
            uiconfirm(app.UIFigure, sprintf(msg), title, ...
                "Options",["OK"],...
                "Icon","success");

        end

        % Value changed function: MinorgridCheckBox
        function MinorgridCheckBoxValueChanged(app, event)
            app.settings.fminor = app.MinorgridCheckBox.Value;
        end

        % Selection changed function: LoggerTree
        function LoggerTreeSelectionChanged(app, event)
            selectedNodes = app.LoggerTree.SelectedNodes;
            if ~isempty(selectedNodes)
                if isequal(selectedNodes.Tag, 'children') % 子ノードを選択中の場合
                    selectedNodes = selectedNodes.Parent; % その親ノードに移動
                end
                app.LegendnameEditField.Value = selectedNodes.NodeData.legendName;
                app.LinecolorColorPicker.Value = reshape(selectedNodes.Icon, 1,3);
                app.AlphaSpinner.Value = selectedNodes.NodeData.alpha;
            end
        end

        % Value changed function: CurrentsavefolderEditField
        function CurrentsavefolderEditFieldValueChanged(app, event)
            app.save.folder = app.CurrentsavefolderEditField.Value;
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            nodes = app.LoggerTree.Children;
            selectedNodes = app.LoggerTree.SelectedNodes;
            if isempty(selectedNodes), return; end % 何も選択していない場合

            if isequal(selectedNodes.Tag, 'children') % 子ノードを選択中の場合
                selectedNodes = selectedNodes.Parent; % その親ノードに移動
            end
            if selectedNodes==nodes(1), return; end % 一番上を選択中の場合

            idx = find(nodes==selectedNodes);
            nodes([idx-1, idx]) = nodes([idx, idx-1]); % 順番入れ替え
            app.LoggerTree.Children = nodes;
            app.LoggerTree.SelectedNodes = selectedNodes;
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            nodes = app.LoggerTree.Children;
            selectedNodes = app.LoggerTree.SelectedNodes;
            if isempty(selectedNodes), return; end % 何も選択していない場合

            if isequal(selectedNodes.Tag, 'children') % 子ノードを選択中の場合
                selectedNodes = selectedNodes.Parent; % その親ノードに移動
            end
            if selectedNodes==nodes(end), return; end % 一番下を選択中の場合

            idx = find(nodes==selectedNodes);
            nodes([idx, idx+1]) = nodes([idx+1, idx]); % 順番入れ替え
            app.LoggerTree.Children = nodes;
            app.LoggerTree.SelectedNodes = selectedNodes;
        end

        % Value changed function: RMSEvaluesoutputCheckBox
        function RMSEvaluesoutputCheckBoxValueChanged(app, event)
            app.save.fRMSEoutput = app.RMSEvaluesoutputCheckBox.Value;
        end

        % Value changed function: ShowlegendCheckBox
        function ShowlegendCheckBoxValueChanged(app, event)
            app.settings.flegend = app.ShowlegendCheckBox.Value;
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all;
        end

        % Button pushed function: ClosefigureswithoutcurrentsettingsButton
        function ClosefigureswithoutcurrentsettingsButtonPushed(app, event)
            all_figs = findobj('Type','figure');
            figNums = cell2mat(app.map.fignum)';
            if isempty(all_figs) || length(all_figs)==length(figNums)
                % figureウィンドウがない or 現状のsettingがプロットされているだけ
                return;
            end

            fig2close = all_figs(~ismember([all_figs.Number], figNums));
            delete(fig2close);
        end

        % Value changed function: ControlcostoutputCheckBox
        function ControlcostoutputCheckBoxValueChanged(app, event)
            app.save.fControlCost = app.ControlcostoutputCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoggerTab = uitab(app.TabGroup);
            app.LoggerTab.Title = 'Logger';
            app.LoggerTree = uitree(app.LoggerTab, 'checkbox');
            app.LoggerTree.SelectionChangedFcn = createCallbackFcn(app, @LoggerTreeSelectionChanged, true);
            % Assign Checked Nodes
            app.LoggerTree.CheckedNodesChangedFcn = createCallbackFcn(app, @LoggerTreeCheckedNodesChanged, true);
            app.LOGGERlistLabel = uilabel(app.LoggerTab);
            app.LOGGERlistLabel.Text = 'LOGGER list';
            app.SetLoggerButton = uibutton(app.LoggerTab, 'push');
            app.SetLoggerButton.ButtonPushedFcn = createCallbackFcn(app, @SetLoggerButtonValueChanged, true);
            app.SetLoggerButton.Text = 'Set LOGGER';
            app.LoadedmatfileLabel = uilabel(app.LoggerTab);
            app.LoadedmatfileLabel.Text = '▼Loaded mat file';
            app.FullpathLabel = uilabel(app.LoggerTab);
            app.FullpathLabel.Text = '        Fullpath';
            app.LegendNameLabel = uilabel(app.LoggerTab);
            app.LegendNameLabel.Text = '        LegendName';
            app.AlphavalueLabel = uilabel(app.LoggerTab);
            app.AlphavalueLabel.Text = '        Alpha value';
            app.DeleteallLOGGERButton = uibutton(app.LoggerTab, 'push');
            app.DeleteallLOGGERButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteallLOGGERButtonPushed, true);
            app.DeleteallLOGGERButton.Text = 'Delete all LOGGER';
            app.DeletecheckedLOGGERButton = uibutton(app.LoggerTab, 'push');
            app.DeletecheckedLOGGERButton.ButtonPushedFcn = createCallbackFcn(app, @DeletecheckedLOGGERButtonPushed, true);
            app.DeletecheckedLOGGERButton.Text = 'Delete checked LOGGER';
            app.LoadaddmatfileLOGGERButton = uibutton(app.LoggerTab, 'push');
            app.LoadaddmatfileLOGGERButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaddmatfileLOGGERButtonPushed, true);
            app.LoadaddmatfileLOGGERButton.Text = {'Load&add'; 'mat file(LOGGER)'};
            app.LOGGERPanel = uipanel(app.LoggerTab);
            app.LOGGERPanel.Title = '選択中のLOGGERに対して設定します';
            app.LegendnameEditFieldLabel = uilabel(app.LOGGERPanel);
            app.LegendnameEditFieldLabel.Text = 'Legend name';
            app.LegendnameEditField = uieditfield(app.LOGGERPanel, 'text');
            app.LegendnameEditField.ValueChangedFcn = createCallbackFcn(app, @LegendnameEditFieldValueChanged, true);
            app.LinecolorColorPickerLabel = uilabel(app.LOGGERPanel);
            app.LinecolorColorPickerLabel.Text = 'Line color';
            app.LinecolorColorPicker = uicolorpicker(app.LOGGERPanel);
            app.LinecolorColorPicker.Value = [0 0.451 0.7412];
            app.LinecolorColorPicker.ValueChangedFcn = createCallbackFcn(app, @LinecolorColorPickerValueChanged, true);
            app.AlphaLabel = uilabel(app.LOGGERPanel);
            app.AlphaLabel.Text = 'Alpha';
            app.AlphaSpinner = uispinner(app.LOGGERPanel);
            app.AlphaSpinner.Step = 0.02;
            app.AlphaSpinner.Limits = [0 1];
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.AlphaSpinner.Value = 1;
            app.Label4alpha = uilabel(app.LOGGERPanel);
            app.Label4alpha.Text = '（プロット線の透明度 0~1）';
            app.ExampleofthelistLabel = uilabel(app.LoggerTab);
            app.ExampleofthelistLabel.Text = '[Example of the list]';
            app.loadingLabel = uilabel(app.LoggerTab);
            app.loadingLabel.Visible = 'off';
            app.loadingLabel.Text = 'loading...   00/00';
            app.UpButton = uibutton(app.LoggerTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.LoggerTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.SelectedloggerpositionchangerLabel = uilabel(app.LoggerTab);
            app.SelectedloggerpositionchangerLabel.Text = {'Selected logger'; 'position changer'};
            app.PlotSaveTab = uitab(app.TabGroup);
            app.PlotSaveTab.Title = 'Plot & Save';
            app.PlotsettingsPanel = uipanel(app.PlotSaveTab);
            app.PlotsettingsPanel.Title = 'Plot settings';
            app.PlotsettingsPanel.ButtonDownFcn = createCallbackFcn(app, @PhaseUpdateCheckboxes, true);
            app.TimerangeLabel = uilabel(app.PlotsettingsPanel);
            app.TimerangeLabel.Text = {'Time'; 'range'};
            app.TimerangeSlider = uislider(app.PlotsettingsPanel, 'range');
            app.TimerangeSlider.Limits = [0 60];
            app.TimerangeSlider.ValueChangedFcn = createCallbackFcn(app, @TimerangeSliderValueChanged, true);
            app.TimerangeSlider.ValueChangingFcn = createCallbackFcn(app, @TimerangeSliderValueChanging, true);
            app.TimerangeSlider.Step = 1;
            app.TimerangeSlider.Value = [0 60];
            app.AxisFontSizeSpinnerLabel = uilabel(app.PlotsettingsPanel);
            app.AxisFontSizeSpinnerLabel.Text = 'Axis FontSize';
            app.AxisFontSizeSpinner = uispinner(app.PlotsettingsPanel);
            app.AxisFontSizeSpinner.Limits = [2 40];
            app.AxisFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxisFontSizeSpinnerValueChanged, true);
            app.AxisFontSizeSpinner.Value = 12;
            app.LabelFontSizeSpinnerLabel = uilabel(app.PlotsettingsPanel);
            app.LabelFontSizeSpinnerLabel.Text = 'Label FontSize';
            app.LabelFontSizeSpinner = uispinner(app.PlotsettingsPanel);
            app.LabelFontSizeSpinner.Limits = [2 40];
            app.LabelFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @LabelFontSizeSpinnerValueChanged, true);
            app.LabelFontSizeSpinner.Value = 16;
            app.LabelFontSizeSpinner_2Label = uilabel(app.PlotsettingsPanel);
            app.LabelFontSizeSpinner_2Label.Text = 'Legend FontSize';
            app.LegendFontSizeSpinner = uispinner(app.PlotsettingsPanel);
            app.LegendFontSizeSpinner.Limits = [2 40];
            app.LegendFontSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @LegendFontSizeSpinnerValueChanged, true);
            app.LegendFontSizeSpinner.Value = 14;
            app.LineWidthSpinnerLabel = uilabel(app.PlotsettingsPanel);
            app.LineWidthSpinnerLabel.Text = 'LineWidth';
            app.LineWidthSpinner = uispinner(app.PlotsettingsPanel);
            app.LineWidthSpinner.Step = 0.25;
            app.LineWidthSpinner.Limits = [0.25 10];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @LineWidthSpinnerValueChanged, true);
            app.LineWidthSpinner.Value = 1.5;
            app.PhaseLabel = uilabel(app.PlotsettingsPanel);
            app.PhaseLabel.Text = 'Phase';
            app.MintimeSpinnerLabel = uilabel(app.PlotsettingsPanel);
            app.MintimeSpinnerLabel.Text = 'Min time';
            app.MintimeSpinner = uispinner(app.PlotsettingsPanel);
            app.MintimeSpinner.Step = 0.05;
            app.MintimeSpinner.Limits = [0 Inf];
            app.MintimeSpinner.ValueDisplayFormat = '%.2f';
            app.MintimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MintimeSpinnerValueChanged, true);
            app.MaxtimeSpinnerLabel = uilabel(app.PlotsettingsPanel);
            app.MaxtimeSpinnerLabel.Text = 'Max time';
            app.MaxtimeSpinner = uispinner(app.PlotsettingsPanel);
            app.MaxtimeSpinner.Step = 0.05;
            app.MaxtimeSpinner.Limits = [0 60];
            app.MaxtimeSpinner.ValueDisplayFormat = '%.2f';
            app.MaxtimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxtimeSpinnerValueChanged, true);
            app.MaxtimeSpinner.Value = 60;
            app.ArmingLabel = uilabel(app.PlotsettingsPanel);
            app.ArmingLabel.Enable = 'off';
            app.ArmingLabel.Visible = 'off';
            app.ArmingLabel.Text = 'Arming';
            app.TakeoffLabel = uilabel(app.PlotsettingsPanel);
            app.TakeoffLabel.Text = '     Takeoff';
            app.FlightLabel = uilabel(app.PlotsettingsPanel);
            app.FlightLabel.Text = '     Flight';
            app.LandingLabel = uilabel(app.PlotsettingsPanel);
            app.LandingLabel.Text = '     Landing';
            app.LandingCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.LandingCheckBox.ValueChangedFcn = createCallbackFcn(app, @LandingCheckBoxValueChanged, true);
            app.LandingCheckBox.Text = '';
            app.FlightCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.FlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlightCheckBoxValueChanged, true);
            app.FlightCheckBox.Text = '';
            app.FlightCheckBox.Value = true;
            app.TakeoffCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.TakeoffCheckBox.ValueChangedFcn = createCallbackFcn(app, @TakeoffCheckBoxValueChanged, true);
            app.TakeoffCheckBox.Text = '';
            app.ArmingCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.ArmingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArmingCheckBoxValueChanged, true);
            app.ArmingCheckBox.Enable = 'off';
            app.ArmingCheckBox.Visible = 'off';
            app.ArmingCheckBox.Text = '';
            app.PhasecolorflagCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.PhasecolorflagCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhasecolorflagCheckBoxValueChanged, true);
            app.PhasecolorflagCheckBox.Visible = 'off';
            app.PhasecolorflagCheckBox.Text = 'Phase color flag';
            app.MinorgridCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.MinorgridCheckBox.ValueChangedFcn = createCallbackFcn(app, @MinorgridCheckBoxValueChanged, true);
            app.MinorgridCheckBox.Text = 'Minor grid';
            app.MinorgridCheckBox.Value = true;
            app.Label4LW = uilabel(app.PlotsettingsPanel);
            app.Label4LW.Text = '0.25~10';
            app.Label4FS = uilabel(app.PlotsettingsPanel);
            app.Label4FS.Text = '2~40';
            app.ShowlegendCheckBox = uicheckbox(app.PlotsettingsPanel);
            app.ShowlegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlegendCheckBoxValueChanged, true);
            app.ShowlegendCheckBox.Text = 'Show legend';
            app.ShowlegendCheckBox.Value = true;
            app.TargetsAttributesLabelssettingsPanel = uipanel(app.PlotSaveTab);
            app.TargetsAttributesLabelssettingsPanel.Title = 'Targets, Attributes & Labels settings';
            app.UITable = uitable(app.TargetsAttributesLabelssettingsPanel);
            app.UITable.ColumnName = {'Use'; 'Target'; 'Att'; 'xlabel'; 'ylabel'; 'zlabel'; 'legend'; 'agentID'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false false false false false false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.TargetDropDown = uidropdown(app.TargetsAttributesLabelssettingsPanel);
            app.TargetDropDown.Items = {'p'};
            app.TargetDropDown.Value = 'p';
            app.DeleteselectedtargetButton = uibutton(app.TargetsAttributesLabelssettingsPanel, 'push');
            app.DeleteselectedtargetButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedtargetButtonPushed, true);
            app.DeleteselectedtargetButton.Text = '-> Delete selected target';
            app.EditselectedtargetButton = uibutton(app.TargetsAttributesLabelssettingsPanel, 'push');
            app.EditselectedtargetButton.ButtonPushedFcn = createCallbackFcn(app, @EditselectedtargetButtonPushed, true);
            app.EditselectedtargetButton.Text = '-> Edit selected target';
            app.AddtargetButton = uibutton(app.TargetsAttributesLabelssettingsPanel, 'push');
            app.AddtargetButton.ButtonPushedFcn = createCallbackFcn(app, @AddtargetButtonPushed, true);
            app.AddtargetButton.Text = 'Add target';
            app.PlotmodeSwitchLabel = uilabel(app.TargetsAttributesLabelssettingsPanel);
            app.PlotmodeSwitchLabel.Text = 'Plot mode';
            app.PlotmodeSwitch = uiswitch(app.TargetsAttributesLabelssettingsPanel, 'slider');
            app.PlotmodeSwitch.Items = {'subfig', 'onefig'};
            app.PlotmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotmodeSwitchValueChanged, true);
            app.PlotmodeSwitch.Value = 'subfig';
            app.subfigLamp = uilamp(app.TargetsAttributesLabelssettingsPanel);
            app.onefigLamp = uilamp(app.TargetsAttributesLabelssettingsPanel);
            app.LoadsettingsButton = uibutton(app.TargetsAttributesLabelssettingsPanel, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.TargetLabel = uilabel(app.TargetsAttributesLabelssettingsPanel);
            app.TargetLabel.Text = 'Target';
            app.loadingLabel4settings = uilabel(app.TargetsAttributesLabelssettingsPanel);
            app.loadingLabel4settings.Visible = 'off';
            app.loadingLabel4settings.Text = 'loading...   00/00';
            app.SavesettingsPanel = uipanel(app.PlotSaveTab);
            app.SavesettingsPanel.Title = 'Save settings';
            app.SavefigButton = uibutton(app.SavesettingsPanel, 'push');
            app.SavefigButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigButtonPushed, true);
            app.SavefigButton.Text = 'Save fig';
            app.SelectsavefolderButton = uibutton(app.SavesettingsPanel, 'push');
            app.SelectsavefolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsavefolderButtonPushed, true);
            app.SelectsavefolderButton.Text = 'Select save folder';
            app.SavenameEditFieldLabel = uilabel(app.SavesettingsPanel);
            app.SavenameEditFieldLabel.Text = 'Save name:';
            app.SavenameEditField = uieditfield(app.SavesettingsPanel, 'text');
            app.SavenameEditField.ValueChangedFcn = createCallbackFcn(app, @SavenameEditFieldValueChanged, true);
            app.SavestyleButtonGroup = uibuttongroup(app.SavesettingsPanel);
            app.SavestyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SavestyleButtonGroupSelectionChanged, true);
            app.SavestyleButtonGroup.Title = 'Save style:';
            app.pdfButton = uiradiobutton(app.SavestyleButtonGroup);
            app.pdfButton.Text = 'pdf';
            app.pdfButton.Value = true;
            app.jpgButton = uiradiobutton(app.SavestyleButtonGroup);
            app.jpgButton.Text = 'jpg';
            app.pngButton = uiradiobutton(app.SavestyleButtonGroup);
            app.pngButton.Text = 'png';
            app.epsButton = uiradiobutton(app.SavestyleButtonGroup);
            app.epsButton.Text = 'eps';
            app.fig = uiradiobutton(app.SavestyleButtonGroup);
            app.fig.Text = 'fig';
            app.CurrentsavefolderEditFieldLabel = uilabel(app.SavesettingsPanel);
            app.CurrentsavefolderEditFieldLabel.Text = 'Current save folder:';
            app.CurrentsavefolderEditField = uieditfield(app.SavesettingsPanel, 'text');
            app.CurrentsavefolderEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentsavefolderEditFieldValueChanged, true);
            app.CurrentsavefolderEditField.Value = 'plot\fig';
            app.RMSEvaluesoutputCheckBox = uicheckbox(app.SavesettingsPanel);
            app.RMSEvaluesoutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @RMSEvaluesoutputCheckBoxValueChanged, true);
            app.RMSEvaluesoutputCheckBox.Text = 'RMSE values output';
            app.ControlcostoutputCheckBox = uicheckbox(app.SavesettingsPanel);
            app.ControlcostoutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlcostoutputCheckBoxValueChanged, true);
            app.ControlcostoutputCheckBox.Text = 'Control cost output';
            app.ExceloutputLabel = uilabel(app.SavesettingsPanel);
            app.ExceloutputLabel.Text = 'Excel output';
            app.PlotButton = uibutton(app.PlotSaveTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CloseallfiguresButton = uibutton(app.PlotSaveTab, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.ClosefigureswithoutcurrentsettingsButton = uibutton(app.PlotSaveTab, 'push');
            app.ClosefigureswithoutcurrentsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ClosefigureswithoutcurrentsettingsButtonPushed, true);
            app.ClosefigureswithoutcurrentsettingsButton.Text = {'Close figures without'; 'current settings'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PLOTTER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end